classdef img_coding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image4                    matlab.ui.control.Image
        Image3                    matlab.ui.control.Image
        DifferentReadingAlgosSequenceLenghtsLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        ZigZagEditField           matlab.ui.control.EditField
        ZigZagEditFieldLabel      matlab.ui.control.Label
        VerticalEditField         matlab.ui.control.EditField
        VerticalEditFieldLabel    matlab.ui.control.Label
        HorizontalEditField       matlab.ui.control.EditField
        HorizontalEditFieldLabel  matlab.ui.control.Label
        ExecuteButton             matlab.ui.control.Button
        Image2                    matlab.ui.control.Image
        GoBackButton              matlab.ui.control.Button
        Thelonguestsequenceof0sisdonebyLabel  matlab.ui.control.Label
        OpenButton                matlab.ui.control.Button
        InsertImageLabel          matlab.ui.control.Label
        ImageCodingLabel          matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end


    properties (Access = private)
        rawImage % Raw image data selected by the user
        squareImage % Image data enocded vertically, horizontally, or in zigzag
        vectorImage_H 
        vectorImage_Z
        vectorImage_V 
        longestZerosSequence_H 
        longestZerosSequence_V
        longestZerosSequence_Z 
    end
    
    methods (Access = private)

        function updateVectorOutput(app)
            app.longestZerosSequence_H          = longestZero(app.vectorImage_H);
            app.longestZerosSequence_Z          = longestZero(app.vectorImage_Z);
            app.longestZerosSequence_V          = longestZero(app.vectorImage_V);
            app.HorizontalEditField.Value       = string(app.longestZerosSequence_H);
            app.ZigZagEditField.Value           = string(app.longestZerosSequence_Z);
            app.VerticalEditField.Value         = string(app.longestZerosSequence_V);
            H = app.longestZerosSequence_H;
            V = app.longestZerosSequence_V;
            Z = app.longestZerosSequence_Z;
            
            max_HV = max(H,V);
            max_value = max(max_HV,Z);
            if max_value == H
                app.Label.Text = 'Horizontal Reading Algo';
            elseif max_value == Z
                app.Label.Text = 'ZigZag Reading Algo';
            elseif max_value == V
                app.Label.Text = 'Vertical Reading Algo';
            end
            if H==V && V==Z
                app.Label.Text = 'All algorithms are the same in this case';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            Welcomepage
            close(app.UIFigure);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Open a file dialog to choose an image file
            [filename, filepath] = uigetfile({'*.jpg;*.png;', 'Image Files (*.jpg, *.png)'});

            % Check if a file was selected
            if ischar(filename)
                % Load the selected image
                imageFilePath = fullfile(filepath, filename);
                app.rawImage = imread(imageFilePath);

                % prepare the raw image (crop it to a square)
                [originalShape, croppedShape, croppedImage] =  prepareImage(app.rawImage);
                app.squareImage = croppedImage;
                if originalShape == croppedShape
                    app.InsertImageLabel.Text = "Image is Ready!";
                else
                    app.InsertImageLabel.Text = "Image was cropped and Ready!";
                end
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            app.vectorImage_H = horiRead(app.squareImage);
            app.vectorImage_Z = zigzagRead(app.squareImage);
            app.vectorImage_V = vertRead(app.squareImage);
            app.updateVectorOutput();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'tswira.png';
            app.ImageCodingLabel = uilabel(app.UIFigure);
            app.ImageCodingLabel.Text = 'Image Coding';
            app.InsertImageLabel = uilabel(app.UIFigure);
            app.InsertImageLabel.Text = 'Insert Image...';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open...';
            app.Thelonguestsequenceof0sisdonebyLabel = uilabel(app.UIFigure);
            app.Thelonguestsequenceof0sisdonebyLabel.Text = 'The longuest sequence of 0''s is done by';
            app.GoBackButton = uibutton(app.UIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'go_back.png';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.HorizontalEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontalEditFieldLabel.Text = 'Horizontal';
            app.HorizontalEditField = uieditfield(app.UIFigure, 'text');
            app.VerticalEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalEditFieldLabel.Text = 'Vertical';
            app.VerticalEditField = uieditfield(app.UIFigure, 'text');
            app.ZigZagEditFieldLabel = uilabel(app.UIFigure);
            app.ZigZagEditFieldLabel.Text = 'ZigZag';
            app.ZigZagEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DifferentReadingAlgosSequenceLenghtsLabel = uilabel(app.UIFigure);
            app.DifferentReadingAlgosSequenceLenghtsLabel.Text = 'Different Reading Algo''s (Sequence Lenghts)';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'Image_01Open_2.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'Circle_CheckYes.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = img_coding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end