classdef txt_coding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image3                   matlab.ui.control.Image
        Result_2                 matlab.ui.control.Label
        Result                   matlab.ui.control.Label
        Image2_2                 matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        ShanonFanoLabel          matlab.ui.control.Label
        HuffmanLabel             matlab.ui.control.Label
        CompressionrateLabel     matlab.ui.control.Label
        EditField_3              matlab.ui.control.EditField
        EditField_2              matlab.ui.control.EditField
        EncodedphraseisLabel     matlab.ui.control.Label
        ThebestalgorithmisLabel  matlab.ui.control.Label
        EncodeButton             matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        InserttextLabel          matlab.ui.control.Label
        GoBackButton             matlab.ui.control.Button
        TextCodingLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            Welcomepage
            close(app.UIFigure);
        end

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
                message = app.EditField.Value;
                symbole_unique = unique(message);
                proba = zeros(1, length(symbole_unique)); 
                
                for i = 1:length(symbole_unique)
                   k = find(message == symbole_unique(i));
                   proba(i) = length(k) / length(message);
                end
                
                m_code = ShannonFanoCode(symbole_unique, proba);
                code_shano = EntropyEncoder(message, m_code);
                Taux_compression_shannon = length(code_shano) / (length(message)*8);
                
                m_code2 = huffmandict(double(symbole_unique), proba);
                code_huffm = huffmanenco(message, m_code2);
                Taux_compression_huff = length(code_huffm) / (length(message) * 8);
                
                % Comparaison des taux de compression
                if Taux_compression_shannon > Taux_compression_huff
                    methode = 'Shannon';
                    Taux_compression = Taux_compression_shannon;
                    result = sprintf('The Best algorithm is %s with %.2f in compression rate', methode, Taux_compression_shannon);
                    app.Result.Text = sprintf('%d',code_shano);
                elseif Taux_compression_huff > Taux_compression_shannon
                    methode = 'Huffman';
                    Taux_compression = Taux_compression_huff;
                    result = sprintf('The Best algorithm is %s with %.2f in compression rate', methode, Taux_compression_huff);
                    app.Result.Text = sprintf('%d',code_huffm);
                else
                    result = sprintf('Both algorithms are good for this sample : %.2f%% for Shannon and Huffman', Taux_compression_shannon);
                    app.Result.Text = sprintf('%d',code_huffm);
                end
                sh_comp = sprintf('%.2f',Taux_compression_shannon);
                hf_comp = sprintf('%.2f',Taux_compression_huff);
                
                % Impression de la meilleure méthode et du taux de compression correspondant
                app.ThebestalgorithmisLabel.Text = result;
                app.EditField_3.Value = sh_comp;
                app.EditField_2.Value = hf_comp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextCodingLabel = uilabel(app.UIFigure);
            app.TextCodingLabel.Text = 'Text Coding';
            app.GoBackButton = uibutton(app.UIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.InserttextLabel = uilabel(app.UIFigure);
            app.InserttextLabel.Text = 'Insert text';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EncodeButton = uibutton(app.UIFigure, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Text = 'Encode';
            app.ThebestalgorithmisLabel = uilabel(app.UIFigure);
            app.ThebestalgorithmisLabel.Text = 'The best algorithm is : ';
            app.EncodedphraseisLabel = uilabel(app.UIFigure);
            app.EncodedphraseisLabel.Text = 'Encoded phrase is :';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.CompressionrateLabel = uilabel(app.UIFigure);
            app.CompressionrateLabel.Text = 'Compression rate (%) ';
            app.HuffmanLabel = uilabel(app.UIFigure);
            app.HuffmanLabel.Text = 'Huffman ';
            app.ShanonFanoLabel = uilabel(app.UIFigure);
            app.ShanonFanoLabel.Text = 'Shanon Fano';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'tswira.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'go_back.png';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'go_back.png';
            app.Result = uilabel(app.UIFigure);
            app.Result.Text = '';
            app.Result_2 = uilabel(app.UIFigure);
            app.Result_2.Text = '';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'CodeEncode_2.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = txt_coding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end